classdef camii_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        File                            matlab.ui.control.Label
        FileLabel                       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisTab                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        zerothresh                      matlab.ui.control.Slider
        partnerthresh                   matlab.ui.control.Slider
        zerothresh_label                matlab.ui.control.Label
        partnerthresh_label             matlab.ui.control.Label
        BufferSize                      matlab.ui.control.Slider
        BufferSizeLabel                 matlab.ui.control.Label
        ConfigureAnalysisLabel          matlab.ui.control.Label
        FileLabel_2                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DataAnalysisLabel               matlab.ui.control.Label
        DataImportLabel                 matlab.ui.control.Label
        AnalyseButton                   matlab.ui.control.Button
        PathLabel                       matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        VisualisationExplorationTab     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SegmentationViewCheckBox        matlab.ui.control.CheckBox
        FeatureDropDown                 matlab.ui.control.DropDown
        FeatureDropDownLabel            matlab.ui.control.Label
        InteractionOverlayCheckBox      matlab.ui.control.CheckBox
        UIAxesEvoT                      matlab.ui.control.UIAxes
        UIAxesDistC                     matlab.ui.control.UIAxes
        UIAxesDistT                     matlab.ui.control.UIAxes
        UIAxesDistComp                  matlab.ui.control.UIAxes
        UIAxesEvoC                      matlab.ui.control.UIAxes
        ExportTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        IdentifierEditField             matlab.ui.control.EditField
        IdentifierEditFieldLabel        matlab.ui.control.Label
        TableFormatDropDown             matlab.ui.control.DropDown
        TableFormatDropDownLabel        matlab.ui.control.Label
        ExportFormatDropDown            matlab.ui.control.DropDown
        ExportFormatDropDownLabel       matlab.ui.control.Label
        SelectedFolderLabel             matlab.ui.control.Label
        SelectExportFolderButton        matlab.ui.control.Button
        InteractionFeaturesListBox      matlab.ui.control.ListBox
        InteractionFeaturesListBoxLabel  matlab.ui.control.Label
        InteractionTypesCheckBox        matlab.ui.control.CheckBox
        StatisticsFeaturesListBox       matlab.ui.control.ListBox
        StatisticsFeaturesListBoxLabel  matlab.ui.control.Label
        StatisticsCheckBox              matlab.ui.control.CheckBox
        SegmentsFeaturesListBox         matlab.ui.control.ListBox
        SegmentsFeaturesListBoxLabel    matlab.ui.control.Label
        SegmentsCheckBox                matlab.ui.control.CheckBox
        GraphFeaturesListBox            matlab.ui.control.ListBox
        GraphFeaturesListBoxLabel       matlab.ui.control.Label
        GraphsCheckBox                  matlab.ui.control.CheckBox
        ExportButton                    matlab.ui.control.Button
        ResultTablesCheckBox            matlab.ui.control.CheckBox
        AboutTab                        matlab.ui.container.Tab
        Hyperlink4                      matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        InformationonLicensingandThirdPartyDependenciesLabel  matlab.ui.control.Label
        WelcometoCAMIIv011asdLabel_3    matlab.ui.control.Label
        Status                          matlab.ui.control.Label
        AnalysisStatus                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MidiFile % MIDI file path
        ResultStruct %result of camii_analysis
        fileName % name of file
        ExportFolder string = "" % Description
        LastPath = pwd; % Standardordner für File Select
        LastPathExport = pwd; % Standardordner für File Export
    end
    
    methods (Access = private)
        
        function updatePlot(app)
                        
            if isempty(app.ResultStruct)
                return
            end

            %import data
            data = app.ResultStruct.data.smoothed;
            time = data.Time;
            types = app.ResultStruct.types;
            typestotal = app.ResultStruct.typestotal;
            segments = app.ResultStruct.segments;

            iatypenames = ["Dependent","Approach","Follower","Partner", "Leader","Resister","Neutral"];

            colorpalette = [1 0 0;1 1 0;1 0.4 0.2;0.4 0.7 0.2;0 0.4 0.8;0.5 0.2 0.6;0.9 0.9 0.9];

            %set manually chosen values
            feat = app.FeatureDropDown.Value; % Feature Dropdown 

            %Handle Mapping
            featC = strcat(feat, "C");
            featT = strcat(feat, "T");
                
%--------------------------------------------------
% Client Plot
%--------------------------------------------------

            cla(app.UIAxesEvoC, 'reset')
            hold(app.UIAxesEvoC,'on')

            plot(app.UIAxesEvoC, time, data.(featC), 'g', 'LineWidth', 0.5, 'DisplayName','Client');
            plot(app.UIAxesEvoC, time, data.(featT), 'k--', 'LineWidth',0.5,'DisplayName','Therapist');
                
            if app.SegmentationViewCheckBox.Value %Client Segmentation

                extrema_c_idx = segments.(feat).C.idx(segments.(feat).C.type == "max" | segments.(feat).C.type == "min" | segments.(feat).C.type == "begin" | segments.(feat).C.type == "end"); %extract extrema indexes for client
                bends_c_idx = segments.(feat).C.idx(segments.(feat).C.type == "bend"); %extract extrema indexes for client

                plot(app.UIAxesEvoC, time(extrema_c_idx), data.(featC)(extrema_c_idx), '^g', 'LineWidth', 0.5, 'DisplayName','Client Extrema');
                plot(app.UIAxesEvoC, time(bends_c_idx), data.(featC)(bends_c_idx), 'squareg', 'LineWidth', 0.5, 'DisplayName','Client Bends');

                crossings_times = segments.(feat).crossings.time;
                crossings_values = segments.(feat).crossings.value;

                plot(app.UIAxesEvoC, crossings_times, crossings_values, 'or', 'LineWidth', 0.5, 'DisplayName', 'Crossings')

            end

            if app.InteractionOverlayCheckBox.Value %Client Overlay

                for iatypeidx = 1:numel(iatypenames)
            
                    ident = types.c.(feat) == iatypenames(iatypeidx);
            
                    beginnings = strfind(ident',[0 1]) + 1;
                    ends = strfind(ident',[1 0]);
            
                    if ident(1)
                        beginnings = [1 beginnings];
                    end
            
                    if ident(end)
                        ends(end+1) = numel(ident);
                    end
            
                    iaindexes = sort([beginnings; ends]');
            
                    for regIdx = 1:size(iaindexes,1)
                        region = xregion(app.UIAxesEvoC, time(iaindexes(regIdx,:)), FaceColor=colorpalette(iatypeidx,:), FaceAlpha=0.1);            
                        if regIdx == 1
                            region.DisplayName = iatypenames(iatypeidx);
                        else
                            region.HandleVisibility = "off";
                        end            
                    end
                end
            end

            ylabel(app.UIAxesEvoC,feat) %Client Achsen
            xlabel(app.UIAxesEvoC,'Time (s)')
            title(app.UIAxesEvoC, 'Client Evolution')
            legend(app.UIAxesEvoC, 'Location','eastoutside')            
            hold(app.UIAxesEvoC,'off')
            
%--------------------------------------------------
% Therapist Plot
%--------------------------------------------------
          
            cla(app.UIAxesEvoT, 'reset')
            hold(app.UIAxesEvoT,'on')

            plot(app.UIAxesEvoT, time, data.(featC), 'g--', 'LineWidth',0.5, 'DisplayName','Client');
            plot(app.UIAxesEvoT, time, data.(featT), 'k', 'LineWidth',0.5, 'DisplayName','Therapist');
            
            if app.SegmentationViewCheckBox.Value %Therapist Segmentation        

                extrema_t_idx = segments.(feat).T.idx(segments.(feat).T.type == "max" | segments.(feat).T.type == "min" | segments.(feat).T.type == "begin" | segments.(feat).T.type == "end"); %extract extrema indexes for client
                bends_t_idx = segments.(feat).T.idx(segments.(feat).T.type == "bend"); %extract extrema indexes for client

                plot(app.UIAxesEvoT, time(extrema_t_idx), data.(featT)(extrema_t_idx), '^k', 'LineWidth', 0.5, 'DisplayName','Therapist Extrema');
                plot(app.UIAxesEvoT, time(bends_t_idx), data.(featT)(bends_t_idx), 'squarek', 'LineWidth', 0.5, 'DisplayName','Therapist Bends');
                     
                crossings_times = segments.(feat).crossings.time;
                crossings_values = segments.(feat).crossings.value;

                plot(app.UIAxesEvoT, crossings_times, crossings_values, 'or', 'LineWidth', 0.5, 'DisplayName', 'Crossings')

            end

            if app.InteractionOverlayCheckBox.Value %Therapist Overlay
            
                for iatypeidx = 1:numel(iatypenames)
            
                    ident = types.t.(feat) == iatypenames(iatypeidx);
            
                    beginnings = strfind(ident',[0 1]) + 1;
                    ends = strfind(ident',[1 0]);
            
                    if ident(1)
                        beginnings = [1 beginnings];
                    end
            
                    if ident(end)
                        ends(end+1) = numel(ident);
                    end
            
                    iaindexes = sort([beginnings; ends]');
            
                    for regIdx = 1:size(iaindexes,1)            
                        region = xregion( app.UIAxesEvoT, time(iaindexes(regIdx,:)), FaceColor=colorpalette(iatypeidx,:), FaceAlpha=0.1);            
                        if regIdx == 1
                            region.DisplayName = iatypenames(iatypeidx);
                        else
                            region.HandleVisibility = "off";
                        end
            
                    end
                end
            end
            ylabel(app.UIAxesEvoT,feat)
            xlabel(app.UIAxesEvoT,'Time (s)')    
            title(app.UIAxesEvoT, 'Therapist Evolution')
            legend(app.UIAxesEvoT, 'Location','eastoutside')            
            hold(app.UIAxesEvoT,'off')

%--------------------------------------------------
% Distribution Visibility Logic
%--------------------------------------------------

    if app.InteractionOverlayCheckBox.Value
    
        app.UIAxesDistC.Visible = "on";
        app.UIAxesDistT.Visible = "on";
        app.UIAxesDistComp.Visible = "on";
    
    else

        cla(app.UIAxesDistC, "reset")
        cla(app.UIAxesDistT, "reset")
        cla(app.UIAxesDistComp, "reset")

        app.UIAxesDistC.Visible = "off";
        app.UIAxesDistT.Visible = "off";
        app.UIAxesDistComp.Visible = "off";

    end

%--------------------------------------------------
% Client Distribution
%--------------------------------------------------

if app.InteractionOverlayCheckBox.Value

    cla(app.UIAxesDistC,'reset')

    distributionC = typestotal.c.(feat){1,:};

    % label
    labelsC = strcat(iatypenames, " ", string(round(distributionC*100,1)), "%");

    % Pie Chart
    p = pie(app.UIAxesDistC, distributionC, labelsC);

    % use colours
    patches = p(1:2:end);

    for k = 1:numel(patches)
        patches(k).FaceColor = colorpalette(k,:);
        patches(k).FaceAlpha = 0.3;
    end

    title(app.UIAxesDistC,'Client Distribution')

end

%--------------------------------------------------
% Therapist Distribution
%--------------------------------------------------

if app.InteractionOverlayCheckBox.Value

    cla(app.UIAxesDistT,'reset')

    distributionT = typestotal.t.(feat){1,:};

    % label
    labelsT = strcat(iatypenames, " ", string(round(distributionT*100,1)), "%");

    % Pie Chart
    p = pie(app.UIAxesDistT, distributionT, labelsT);

    % use colours
    patches = p(1:2:end);

    for k = 1:numel(patches)
        patches(k).FaceColor = colorpalette(k,:);
        patches(k).FaceAlpha = 0.3;
    end

    title(app.UIAxesDistT,'Therapist Distribution')

end

%--------------------------------------------------
% Distribution Comparison
%--------------------------------------------------

    if app.InteractionOverlayCheckBox.Value
    
        typesdiff = typestotal.c.(feat){1,:} - typestotal.t.(feat){1,:};
        cla(app.UIAxesDistComp, 'reset')

        bar(app.UIAxesDistComp, 1:7, typesdiff, 'FaceColor','flat', 'CData',colorpalette, 'FaceAlpha',0.1);
        
        xticks(app.UIAxesDistComp,1:7)
        xticklabels(app.UIAxesDistComp,iatypenames)
        ylabel(app.UIAxesDistComp, 'Difference in Decimal Percentage')
        title(app.UIAxesDistComp, 'Distribution Comparison')

        xlim(app.UIAxesDistComp,[0 8])
        ylim(app.UIAxesDistComp,[-1 1])
        yline(app.UIAxesDistComp,0,'k-')

        yyaxis(app.UIAxesDistComp, "right")
        yticks(app.UIAxesDistComp, [])
        ylabel(app.UIAxesDistComp, 'Therapist                         Client', Color="k")

        ax = app.UIAxesDistComp;
        ax.YColor = 'k';
        ax.YTick = [];
    
    end
        
        end
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.mid'}, 'Select MIDI file', app.LastPath);

            if isequal(file,0)
                return
            end

            app.LastPath = path;

            app.fileName = file;
            app.MidiFile = fullfile(path, file);
            
            app.FileLabel.Text = app.MidiFile; %Label für File in der GUI
            app.PathLabel.Text = app.MidiFile; %Label für File in der GUI
            
            app.AnalysisStatus.Text = "Ready for Analysis";
            app.AnalyseButton.Enable = "on";
            
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
           
            if isempty(app.MidiFile)
                uialert(app.UIFigure,...
                        'Please select a MIDI file first.',...
                        'No File Selected');
                return
            end

            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Analysis Running', ...
                'Message', 'Please Wait ...', ...
                'Indeterminate', 'on', ...
                Cancelable='on');
            
            drawnow

            try
                        app.AnalysisStatus.Text = "Analysis Running...";
                        app.AnalyseButton.Text = "Analysing...";
                        app.AnalyseButton.Enable = 'off';
                        drawnow
                        app.ResultStruct = camii_analysis(app.MidiFile, ...
                            'buffer_size', app.BufferSize.Value, ...
                            'partner_threshold',app.partnerthresh.Value, ...
                            'zero_threshold', app.zerothresh.Value);
                        updatePlot(app)
                        app.AnalysisStatus.Text = "Analysis succeeded";
                        app.AnalyseButton.Text = "Analyse";

                        app.TabGroup.SelectedTab = app.VisualisationExplorationTab;

            catch ME
                        uialert(app.UIFigure, ME.message, 'Analysis Error');
                        app.AnalysisStatus.Text = "Analysis failed";

                        
            end

            close(d)

            app.AnalyseButton.Enable = 'on';
            app.InteractionOverlayCheckBox.Enable = "on";
            app.SegmentationViewCheckBox.Enable = "on";
            app.FeatureDropDown.Enable = "on";

            app.InteractionTypesCheckBox.Enable = "on";
            app.StatisticsCheckBox.Enable = "on";
            app.SegmentsCheckBox.Enable = "on";
            app.GraphsCheckBox.Enable = "on";
            app.ExportFormatDropDown.Enable = "on";
            app.ExportButton.Enable = "on";
            app.ResultTablesCheckBox.Enable = "on";
            app.IdentifierEditField.Enable = "on";

        end

        % Value changed function: FeatureDropDown
        function FeatureDropDownValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: InteractionOverlayCheckBox
        function InteractionOverlayCheckBoxValueChanged(app, event)
            updatePlot(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
        
        app.ExportButton.Enable = 'off';
        app.ExportButton.Text = "Exporting...";
        app.AnalysisStatus.Text = "Data Export in Process...";
        
        d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Export Running', ...
                'Message', 'Please Wait ...', ...
                'Indeterminate', 'on', ...
                Cancelable='on');
            
        drawnow;  
        
        try

            result_struct = app.ResultStruct;
    
            camii_export(result_struct, ...
            clno = app.IdentifierEditField.Value, ...
            export_graphs   = app.GraphsCheckBox.Value, ...
            export_segments  = app.SegmentsCheckBox.Value, ...
            export_stats     = app.StatisticsCheckBox.Value, ...
            export_types     = app.InteractionTypesCheckBox.Value, ...
            export_results   = app.ResultTablesCheckBox.Value, ...
            ExportFormat    = app.ExportFormatDropDown.Value, ...
            TableFormat     = app.TableFormatDropDown.Value, ...
            GraphFeatures = app.GraphFeaturesListBox.Value, ...
            SegFeatures   = app.SegmentsFeaturesListBox.Value, ...
            StatFeatures  = app.StatisticsFeaturesListBox.Value, ...
            TypeFeatures  = app.InteractionFeaturesListBox.Value, ...
            OutputFolder= app.ExportFolder)

            app.AnalysisStatus.Text = "Export completed successfully.";

        catch ME

            uialert(app.UIFigure, ME.message, 'Export Error');
            app.AnalysisStatus.Text = "Export failed.";
            
        end
    
        close(d)

        app.ExportButton.Text = "Export";
        app.ExportButton.Enable = 'on';
        app.TabGroup.SelectedTab = app.AboutTab;

        
        end

        % Button pushed function: SelectExportFolderButton
        function SelectExportFolderButtonPushed(app, event)
            
            folder = uigetdir(app.LastPathExport, 'Select File Export Location');

            if folder == 0
                return % user cancelled
            end
            
            app.LastPathExport = folder;

            app.ExportFolder = folder;
            
            app.SelectedFolderLabel.Text = folder;
            
            
        end

        % Value changed function: ResultTablesCheckBox
        function ResultTablesCheckBoxValueChanged(app, event)
            value = app.ResultTablesCheckBox.Value;
            
            if value == 1
                app.TableFormatDropDown.Enable = "on";
            else
                app.TableFormatDropDown.Enable = "off";
            end

        end

        % Value changed function: GraphsCheckBox
        function GraphsCheckBoxValueChanged(app, event)
            value = app.GraphsCheckBox.Value;
            
            if value == 1
                app.GraphFeaturesListBox.Enable = "on";
            else
                app.GraphFeaturesListBox.Enable = "off";
            end

        end

        % Value changed function: SegmentsCheckBox
        function SegmentsCheckBoxValueChanged(app, event)
            value = app.SegmentsCheckBox.Value;
            
            if value == 1
                app.SegmentsFeaturesListBox.Enable = "on";
            else
                app.SegmentsFeaturesListBox.Enable = "off";
            end
            
        end

        % Value changed function: StatisticsCheckBox
        function StatisticsCheckBoxValueChanged(app, event)
            value = app.StatisticsCheckBox.Value;
            
            if value == 1
                app.StatisticsFeaturesListBox.Enable = "on";
            else
                app.StatisticsFeaturesListBox.Enable = "off";
            end
            
        end

        % Value changed function: InteractionTypesCheckBox
        function InteractionTypesCheckBoxValueChanged(app, event)
            value = app.InteractionTypesCheckBox.Value;
            
            if value == 1
                app.InteractionFeaturesListBox.Enable = "on";
            else
                app.InteractionFeaturesListBox.Enable = "off";
            end

        end

        % Value changed function: SegmentationViewCheckBox
        function SegmentationViewCheckBoxValueChanged(app, event)
            updatePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisStatus = uilabel(app.UIFigure);
            app.AnalysisStatus.Text = 'Select MIDI File';
            app.Status = uilabel(app.UIFigure);
            app.Status.Text = 'Status:';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Import/Analysis';
            app.GridLayout = uigridlayout(app.AnalysisTab);
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.SelectFileButton = uibutton(app.GridLayout, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.PathLabel = uilabel(app.GridLayout);
            app.PathLabel.Text = 'No file selected';
            app.AnalyseButton = uibutton(app.GridLayout, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Enable = 'off';
            app.AnalyseButton.Text = 'Analyse';
            app.DataImportLabel = uilabel(app.GridLayout);
            app.DataImportLabel.Text = '1. Data Import:';
            app.DataAnalysisLabel = uilabel(app.GridLayout);
            app.DataAnalysisLabel.Text = '3. Data Analysis:';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Required MIDI Format:'; 'Channel 1 = Client'; 'Channel 2 = Therapist'};
            app.FileLabel_2 = uilabel(app.GridLayout);
            app.FileLabel_2.Text = 'File:';
            app.ConfigureAnalysisLabel = uilabel(app.GridLayout);
            app.ConfigureAnalysisLabel.Text = '2. Configure Analysis:';
            app.BufferSizeLabel = uilabel(app.GridLayout);
            app.BufferSizeLabel.Text = 'Buffer Size (.1s)';
            app.BufferSize = uislider(app.GridLayout);
            app.BufferSize.Limits = [10 200];
            app.BufferSize.MajorTicks = [10 25 50 100 200];
            app.BufferSize.MajorTickLabels = {'10', '25', '50', '100', '200'};
            app.BufferSize.Step = 5;
            app.BufferSize.MinorTicksMode = 'manual';
            app.BufferSize.Value = 25;
            app.partnerthresh_label = uilabel(app.GridLayout);
            app.partnerthresh_label.Text = 'Partner Threshold:';
            app.zerothresh_label = uilabel(app.GridLayout);
            app.zerothresh_label.Text = 'Zero Threshold';
            app.partnerthresh = uislider(app.GridLayout);
            app.partnerthresh.Limits = [0.1 0.5];
            app.partnerthresh.MajorTicks = [0.1 0.2 0.3 0.4 0.5];
            app.partnerthresh.Step = 0.05;
            app.partnerthresh.MinorTicksMode = 'manual';
            app.partnerthresh.Tooltip = {'Defines the maximum difference between affirmative and contradictive action to recognise Partner interactions.'};
            app.partnerthresh.Value = 0.2;
            app.zerothresh = uislider(app.GridLayout);
            app.zerothresh.Limits = [0.01 0.1];
            app.zerothresh.MajorTicks = [0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1];
            app.zerothresh.MajorTickLabels = {'.01', '.02', '.03', '.04', '.05', '.06', '.07', '.08', '.09', '.1'};
            app.zerothresh.Step = 0.01;
            app.zerothresh.MinorTicksMode = 'manual';
            app.zerothresh.Tooltip = {'Defines the percentage threshold to identify an action as neutral (in % of individual range per second) '};
            app.zerothresh.Value = 0.01;
            app.VisualisationExplorationTab = uitab(app.TabGroup);
            app.VisualisationExplorationTab.Title = 'Visualisation/Exploration';
            app.GridLayout2 = uigridlayout(app.VisualisationExplorationTab);
            app.UIAxesEvoC = uiaxes(app.GridLayout2);
            title(app.UIAxesEvoC, 'Client Evolution')
            xlabel(app.UIAxesEvoC, 'Time in Seconds (s)')
            ylabel(app.UIAxesEvoC, 'Feature Value')
            zlabel(app.UIAxesEvoC, 'Z')
            app.UIAxesDistComp = uiaxes(app.GridLayout2);
            title(app.UIAxesDistComp, 'Distribution Comparison')
            xlabel(app.UIAxesDistComp, 'Interaction Types')
            ylabel(app.UIAxesDistComp, 'Decimal Percentage Difference')
            zlabel(app.UIAxesDistComp, 'Z')
            app.UIAxesDistT = uiaxes(app.GridLayout2);
            title(app.UIAxesDistT, 'Therapist Distribution')
            xlabel(app.UIAxesDistT, 'Interaction Types')
            ylabel(app.UIAxesDistT, 'Decimal Percentage')
            zlabel(app.UIAxesDistT, 'Z')
            app.UIAxesDistC = uiaxes(app.GridLayout2);
            title(app.UIAxesDistC, 'Client Distribution')
            xlabel(app.UIAxesDistC, 'Interaction Types')
            ylabel(app.UIAxesDistC, 'Decimal Percentage')
            zlabel(app.UIAxesDistC, 'Z')
            app.UIAxesEvoT = uiaxes(app.GridLayout2);
            title(app.UIAxesEvoT, 'Therapist Evolution')
            xlabel(app.UIAxesEvoT, 'Time in Seconds (s)')
            ylabel(app.UIAxesEvoT, 'Feature Value')
            zlabel(app.UIAxesEvoT, 'Z')
            app.InteractionOverlayCheckBox = uicheckbox(app.GridLayout2);
            app.InteractionOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @InteractionOverlayCheckBoxValueChanged, true);
            app.InteractionOverlayCheckBox.Enable = 'off';
            app.InteractionOverlayCheckBox.Text = 'Interaction Overlay';
            app.FeatureDropDownLabel = uilabel(app.GridLayout2);
            app.FeatureDropDownLabel.Enable = 'off';
            app.FeatureDropDownLabel.Text = 'Feature';
            app.FeatureDropDown = uidropdown(app.GridLayout2);
            app.FeatureDropDown.Items = {'AC', 'Articulation', 'Density', 'Dissonance', 'Duration', 'Majorness', 'MeanPitch', 'MeanVelocity', 'Minorness', 'StandardPitchDeviation', 'Tempo', 'Tonality'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureDropDownValueChanged, true);
            app.FeatureDropDown.Enable = 'off';
            app.FeatureDropDown.Value = 'AC';
            app.SegmentationViewCheckBox = uicheckbox(app.GridLayout2);
            app.SegmentationViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationViewCheckBoxValueChanged, true);
            app.SegmentationViewCheckBox.Enable = 'off';
            app.SegmentationViewCheckBox.Text = 'Segmentation View';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.GridLayout3 = uigridlayout(app.ExportTab);
            app.ResultTablesCheckBox = uicheckbox(app.GridLayout3);
            app.ResultTablesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResultTablesCheckBoxValueChanged, true);
            app.ResultTablesCheckBox.Enable = 'off';
            app.ResultTablesCheckBox.Text = 'Result Tables';
            app.ExportButton = uibutton(app.GridLayout3, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.GraphsCheckBox = uicheckbox(app.GridLayout3);
            app.GraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphsCheckBoxValueChanged, true);
            app.GraphsCheckBox.Enable = 'off';
            app.GraphsCheckBox.Text = 'Graphs';
            app.GraphFeaturesListBoxLabel = uilabel(app.GridLayout3);
            app.GraphFeaturesListBoxLabel.Text = 'Graph Features';
            app.GraphFeaturesListBox = uilistbox(app.GridLayout3);
            app.GraphFeaturesListBox.Items = {'AC', 'Articulation', 'Density', 'Dissonance', 'Duration', 'Majorness', 'MeanPitch', 'MeanVelocity', 'Minorness', 'StandardPitchDeviation', 'Tempo', 'Tonality', 'All'};
            app.GraphFeaturesListBox.Multiselect = 'on';
            app.GraphFeaturesListBox.Enable = 'off';
            app.GraphFeaturesListBox.Value = {'AC'};
            app.SegmentsCheckBox = uicheckbox(app.GridLayout3);
            app.SegmentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentsCheckBoxValueChanged, true);
            app.SegmentsCheckBox.Enable = 'off';
            app.SegmentsCheckBox.Text = 'Segments';
            app.SegmentsFeaturesListBoxLabel = uilabel(app.GridLayout3);
            app.SegmentsFeaturesListBoxLabel.Text = 'Segments Features';
            app.SegmentsFeaturesListBox = uilistbox(app.GridLayout3);
            app.SegmentsFeaturesListBox.Items = {'AC', 'Articulation', 'Density', 'Dissonance', 'Duration', 'Majorness', 'MeanPitch', 'MeanVelocity', 'Minorness', 'StandardPitchDeviation', 'Tempo', 'Tonality', 'All'};
            app.SegmentsFeaturesListBox.Multiselect = 'on';
            app.SegmentsFeaturesListBox.Enable = 'off';
            app.SegmentsFeaturesListBox.Value = {'AC'};
            app.StatisticsCheckBox = uicheckbox(app.GridLayout3);
            app.StatisticsCheckBox.ValueChangedFcn = createCallbackFcn(app, @StatisticsCheckBoxValueChanged, true);
            app.StatisticsCheckBox.Enable = 'off';
            app.StatisticsCheckBox.Text = 'Statistics';
            app.StatisticsFeaturesListBoxLabel = uilabel(app.GridLayout3);
            app.StatisticsFeaturesListBoxLabel.Text = 'Statistics Features';
            app.StatisticsFeaturesListBox = uilistbox(app.GridLayout3);
            app.StatisticsFeaturesListBox.Items = {'AC', 'Articulation', 'Density', 'Dissonance', 'Duration', 'Majorness', 'MeanPitch', 'MeanVelocity', 'Minorness', 'StandardPitchDeviation', 'Tempo', 'Tonality', 'All'};
            app.StatisticsFeaturesListBox.Multiselect = 'on';
            app.StatisticsFeaturesListBox.Enable = 'off';
            app.StatisticsFeaturesListBox.Value = {'AC'};
            app.InteractionTypesCheckBox = uicheckbox(app.GridLayout3);
            app.InteractionTypesCheckBox.ValueChangedFcn = createCallbackFcn(app, @InteractionTypesCheckBoxValueChanged, true);
            app.InteractionTypesCheckBox.Enable = 'off';
            app.InteractionTypesCheckBox.Text = 'Interaction Types';
            app.InteractionFeaturesListBoxLabel = uilabel(app.GridLayout3);
            app.InteractionFeaturesListBoxLabel.Text = 'Interaction Features';
            app.InteractionFeaturesListBox = uilistbox(app.GridLayout3);
            app.InteractionFeaturesListBox.Items = {'AC', 'Articulation', 'Density', 'Dissonance', 'Duration', 'Majorness', 'MeanPitch', 'MeanVelocity', 'Minorness', 'StandardPitchDeviation', 'Tempo', 'Tonality', 'All'};
            app.InteractionFeaturesListBox.Multiselect = 'on';
            app.InteractionFeaturesListBox.Enable = 'off';
            app.InteractionFeaturesListBox.Value = {'AC'};
            app.SelectExportFolderButton = uibutton(app.GridLayout3, 'push');
            app.SelectExportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExportFolderButtonPushed, true);
            app.SelectExportFolderButton.Text = 'Select Export Folder';
            app.SelectedFolderLabel = uilabel(app.GridLayout3);
            app.SelectedFolderLabel.Text = 'Selected Folder...';
            app.ExportFormatDropDownLabel = uilabel(app.GridLayout3);
            app.ExportFormatDropDownLabel.Text = 'Export Format';
            app.ExportFormatDropDown = uidropdown(app.GridLayout3);
            app.ExportFormatDropDown.Items = {'png', 'jpg', 'eps', 'svg'};
            app.ExportFormatDropDown.Enable = 'off';
            app.ExportFormatDropDown.Value = 'png';
            app.TableFormatDropDownLabel = uilabel(app.GridLayout3);
            app.TableFormatDropDownLabel.Text = 'Table Format';
            app.TableFormatDropDown = uidropdown(app.GridLayout3);
            app.TableFormatDropDown.Items = {'xlsx', 'csv'};
            app.TableFormatDropDown.Enable = 'off';
            app.TableFormatDropDown.Value = 'xlsx';
            app.IdentifierEditFieldLabel = uilabel(app.GridLayout3);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.IdentifierEditField = uieditfield(app.GridLayout3, 'text');
            app.IdentifierEditField.Enable = 'off';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.WelcometoCAMIIv011asdLabel_3 = uilabel(app.AboutTab);
            app.WelcometoCAMIIv011asdLabel_3.Text = {'CAMII, Copyright © 2026 Bastian Vobig'; 'Computational Assessment of Musical Interaction in Clinical Improvisation'; ''; 'Version: 0.2.2'; 'Author: Bastian Vobig'; ''; 'DOI:'; 'https://doi.org/10.5281/zenodo.20338644'; ''; 'License:'; 'GNU General Public License v3.0 (GPL-3.0)'; ''; 'This program comes with ABSOLUTELY NO WARRANTY; '; 'This is free software, and you are welcome to redistribute it under certain conditions; '; 'For more lincensing information see "LICENSE" at: '; ''; ''; ''; ''; 'Third-Party Components:'; ''; '• mttblight2 (with permission by Olivier Lartillot)'; ''; '• MIDI Toolbox (Toiviainen, P., & Eerola, T. (2016). MIDI Toolbox 1.1. URL: https://github.com/miditoolbox/1.1)'; ''; '• InterX (NS (2026). Curve intersections (https://ch.mathworks.com/matlabcentral/fileexchange/22441-curve-intersections), MATLAB Central File Exchange. Retrieved June 5, 2026.)'; ''; '• export_fig (Yair Altman (2026). export_fig (https://github.com/altmany/export_fig/releases/tag/v3.55), GitHub. Retrieved June 5, 2026.)'; ''; 'For more information on third party licensing see '; ''; ''; ''; 'For documentation and updates visit: '};
            app.InformationonLicensingandThirdPartyDependenciesLabel = uilabel(app.AboutTab);
            app.InformationonLicensingandThirdPartyDependenciesLabel.Text = 'Information on Licensing and Third Party Dependencies';
            app.Hyperlink = uihyperlink(app.AboutTab);
            app.Hyperlink.URL = 'https://github.com/bvobig/camii/tree/main?tab=GPL-3.0-1-ov-file';
            app.Hyperlink.Text = 'https://github.com/bvobig/camii/tree/main?tab=GPL-3.0-1-ov-file';
            app.Hyperlink3 = uihyperlink(app.AboutTab);
            app.Hyperlink3.URL = 'https://github.com/bvobig/camii';
            app.Hyperlink3.Text = 'https://github.com/bvobig/camii';
            app.Hyperlink4 = uihyperlink(app.AboutTab);
            app.Hyperlink4.URL = 'https://github.com/bvobig/camii/blob/main/THIRD_PARTY_LICENSES.md';
            app.Hyperlink4.Text = 'THIRD_PARTY_LICENSES.md';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = 'No file selected';
            app.File = uilabel(app.UIFigure);
            app.File.Text = 'File:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camii_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end